classdef app1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        RightPanel               matlab.ui.container.Panel
        VLabel                   matlab.ui.control.Label
        HzLabel                  matlab.ui.control.Label
        timeSlider               matlab.ui.control.Slider
        timeSliderLabel          matlab.ui.control.Label
        phaseEditField           matlab.ui.control.NumericEditField
        phaseEditFieldLabel      matlab.ui.control.Label
        FrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        AmplitudeEditField       matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        WaveformDropDown         matlab.ui.control.DropDown
        WaveformDropDownLabel    matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: AmplitudeEditField, FrequencyEditField, 
        % WaveformDropDown, WaveformDropDown, phaseEditField, timeSlider, 
        % timeSlider
        function runButtonPushed(app, event)
            
            Phase = app.phaseEditField.Value;
            freq = app.FrequencyEditField.Value;
            Ampl =app.AmplitudeEditField.Value;
            T = app.timeSlider.Value;
            time = 0:0.10:T;
          
            switch app.WaveformDropDown.Value
                case 'sine'
                    Volt = Ampl*sin(2*pi*freq*time+Phase);
                    plot(app.UIAxes, time, Volt);     
                case 'square'
                    offset=0;
                    duty=50;
                    sq_wav=offset+Ampl*square(2*pi*freq*time,duty);
                    plot(app.UIAxes, time, sq_wav);
                case 'triangle'
                    plot(app.UIAxes, time, Ampl*sawtooth(2*pi*freq*time,1/2)); 
                case 'sawtooth'
                    plot(app.UIAxes, time, Ampl*sawtooth(2*pi*freq*time));
            end          
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {353, 353};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {413, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 'TIME')
            ylabel(app.UIAxes, 'AMPLITUDE')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.WaveformDropDownLabel = uilabel(app.RightPanel);
            app.WaveformDropDownLabel.Text = 'Waveform';
            app.WaveformDropDown = uidropdown(app.RightPanel);
            app.WaveformDropDown.Items = {'sine', 'square', 'triangle', 'sawtooth', 'Option 4'};
            app.WaveformDropDown.DropDownOpeningFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.WaveformDropDown.ValueChangedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.WaveformDropDown.Value = 'sine';
            app.AmplitudeEditFieldLabel = uilabel(app.RightPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.RightPanel, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.FrequencyEditFieldLabel = uilabel(app.RightPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.RightPanel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.phaseEditFieldLabel = uilabel(app.RightPanel);
            app.phaseEditFieldLabel.Text = 'phase';
            app.phaseEditField = uieditfield(app.RightPanel, 'numeric');
            app.phaseEditField.ValueChangedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.timeSliderLabel = uilabel(app.RightPanel);
            app.timeSliderLabel.Text = 'time';
            app.timeSlider = uislider(app.RightPanel);
            app.timeSlider.Limits = [1 20];
            app.timeSlider.MajorTicks = [1 5 10 15 20];
            app.timeSlider.ValueChangedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.timeSlider.ValueChangingFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.timeSlider.MinorTicks = [1 3 5 7.5 10 12.5 15 17.5 20];
            app.timeSlider.Value = 1;
            app.HzLabel = uilabel(app.RightPanel);
            app.HzLabel.Text = 'Hz';
            app.VLabel = uilabel(app.RightPanel);
            app.VLabel.Text = 'V';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end